classdef BoundaryTensorForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BoundaryTensorFormUIFigure  matlab.ui.Figure
        Grid                        matlab.ui.container.GridLayout
        TensorPanel                 matlab.ui.container.GridLayout
        ZZField                     matlab.ui.control.EditField
        ZYField                     matlab.ui.control.EditField
        ZXField                     matlab.ui.control.EditField
        YZField                     matlab.ui.control.EditField
        YYField                     matlab.ui.control.EditField
        YXField                     matlab.ui.control.EditField
        XZField                     matlab.ui.control.EditField
        XYField                     matlab.ui.control.EditField
        XXField                     matlab.ui.control.EditField
        RZLabel                     matlab.ui.control.Label
        RYLabel                     matlab.ui.control.Label
        RXLabel                     matlab.ui.control.Label
        CZLabel                     matlab.ui.control.Label
        CYLabel                     matlab.ui.control.Label
        CXLabel                     matlab.ui.control.Label
        SingleValuePanel            matlab.ui.container.Panel
        SingleValueGrid             matlab.ui.container.GridLayout
        TensorField                 matlab.ui.control.EditField
        ConductivityLabel           matlab.ui.control.Label
        PBCPanel                    matlab.ui.container.Panel
        PBCGrid                     matlab.ui.container.GridLayout
        SlaveSelection              matlab.ui.control.DropDown
        MasterBoundaryLabel_1       matlab.ui.control.Label
        SlaveBoundaryLabel          matlab.ui.control.Label
        MasterBoundaryLabel_2       matlab.ui.control.Label
        SlaveLabel                  matlab.ui.control.Label
        MasterLabel                 matlab.ui.control.Label
        DispersiveGrid              matlab.ui.container.GridLayout
        UnitLabel                   matlab.ui.control.Label
        FrequencyLabel              matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        ButtonGrid                  matlab.ui.container.GridLayout
        DoneButton                  matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        DefineButton                matlab.ui.control.Button
        CButton                     matlab.ui.control.Button
        BoundaryTensorLabel         matlab.ui.control.Label
        BoundaryLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainApp;
        type;
        boundary;% Description
        currentFrequencyIndex=1;
        mode; %mode=0 (single value, no disp),mode=1(single value,disp),mode=2(tensor,no disp),mode=3(tensor,disp),mode=4 (PBC)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Input)
            app.mainApp=MainApp;app.type=Input;InitializeBoundaryTensorForm(app);           
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.currentFrequencyIndex=app.DropDown.ValueIndex;SetBoundaryTensors(app);
        end

        % Button pushed function: DefineButton
        function DefineButtonPushed(app, event)
           GetBoundaryTensors(app);
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            SetBoundaryTensors(app);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            CompleteBoundaryDefinition(app);app.delete;
        end

        % Value changed function: TensorField
        function TensorFieldValueChanged(app, event)
            if(isnan(String2Complex(app.TensorField.Value))),set(app.TensorField,"Value","0");end            
        end

        % Value changed function: XXField
        function XXFieldValueChanged(app, event)
            if(isnan(String2Complex(app.XXField.Value))),set(app.XXField,"Value","0");end            
        end

        % Value changed function: XYField
        function XYFieldValueChanged(app, event)
           if(isnan(String2Complex(app.XYField.Value))),set(app.XYField,"Value","0");end            
        end

        % Value changed function: XZField
        function XZFieldValueChanged(app, event)
            if(isnan(String2Complex(app.XZField.Value))),set(app.XZField,"Value","0");end            
        end

        % Value changed function: YXField
        function YXFieldValueChanged(app, event)
            if(isnan(String2Complex(app.YXField.Value))),set(app.YXField,"Value","0");end            
        end

        % Value changed function: YYField
        function YYFieldValueChanged(app, event)
            if(isnan(String2Complex(app.YYField.Value))),set(app.YYField,"Value","0");end            
        end

        % Value changed function: YZField
        function YZFieldValueChanged(app, event)
            if(isnan(String2Complex(app.YZField.Value))),set(app.YZField,"Value","0");end            
        end

        % Value changed function: ZXField
        function ZXFieldValueChanged(app, event)
            if(isnan(String2Complex(app.ZXField.Value))),set(app.ZXField,"Value","0");end            
        end

        % Value changed function: ZYField
        function ZYFieldValueChanged(app, event)
            if(isnan(String2Complex(app.ZYField.Value))),set(app.ZYField,"Value","0");end            
        end

        % Value changed function: ZZField
        function ZZFieldValueChanged(app, event)
            if(isnan(String2Complex(app.ZZField.Value))),set(app.ZZField,"Value","0");end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BoundaryTensorFormUIFigure = uifigure('Visible', 'off');
            app.BoundaryTensorFormUIFigure.Name = 'Boundary Tensor Form';
            app.Grid = uigridlayout(app.BoundaryTensorFormUIFigure);
            app.BoundaryLabel = uilabel(app.Grid);
            app.BoundaryLabel.Interpreter = 'tex';
            app.BoundaryLabel.Text = {'Impedance  '; ' Boundary Condition'};
            app.BoundaryTensorLabel = uilabel(app.Grid);
            app.BoundaryTensorLabel.Interpreter = 'tex';
            app.BoundaryTensorLabel.Text = 'Wave Impedace Z';
            app.ButtonGrid = uigridlayout(app.Grid);
            app.CButton = uibutton(app.ButtonGrid, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.DefineButton = uibutton(app.ButtonGrid, 'push');
            app.DefineButton.ButtonPushedFcn = createCallbackFcn(app, @DefineButtonPushed, true);
            app.DefineButton.Text = 'Define';
            app.CancelButton = uibutton(app.ButtonGrid, 'push');
            app.CancelButton.Text = 'Cancel';
            app.DoneButton = uibutton(app.ButtonGrid, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.DispersiveGrid = uigridlayout(app.Grid);
            app.DropDown = uidropdown(app.DispersiveGrid);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.FrequencyLabel = uilabel(app.DispersiveGrid);
            app.FrequencyLabel.Text = 'Frequency :';
            app.UnitLabel = uilabel(app.DispersiveGrid);
            app.UnitLabel.Text = 'GHz';
            app.PBCPanel = uipanel(app.Grid);
            app.PBCGrid = uigridlayout(app.PBCPanel);
            app.MasterLabel = uilabel(app.PBCGrid);
            app.MasterLabel.Text = 'Master';
            app.SlaveLabel = uilabel(app.PBCGrid);
            app.SlaveLabel.Text = 'Slave';
            app.MasterBoundaryLabel_2 = uilabel(app.PBCGrid);
            app.MasterBoundaryLabel_2.Text = 'Boundary 1';
            app.SlaveBoundaryLabel = uilabel(app.PBCGrid);
            app.SlaveBoundaryLabel.Text = '';
            app.MasterBoundaryLabel_1 = uilabel(app.PBCGrid);
            app.MasterBoundaryLabel_1.Text = 'Boundary 1';
            app.SlaveSelection = uidropdown(app.PBCGrid);
            app.SlaveSelection.Items = {};
            app.SlaveSelection.Value = {};
            app.SingleValuePanel = uipanel(app.Grid);
            app.SingleValueGrid = uigridlayout(app.SingleValuePanel);
            app.ConductivityLabel = uilabel(app.SingleValueGrid);
            app.ConductivityLabel.Interpreter = 'tex';
            app.ConductivityLabel.Text = 'Conductivity σ';
            app.TensorField = uieditfield(app.SingleValueGrid, 'text');
            app.TensorField.ValueChangedFcn = createCallbackFcn(app, @TensorFieldValueChanged, true);
            app.TensorField.Value = '0';
            app.TensorPanel = uigridlayout(app.Grid);
            app.CXLabel = uilabel(app.TensorPanel);
            app.CXLabel.Text = 'X';
            app.CYLabel = uilabel(app.TensorPanel);
            app.CYLabel.Text = 'Y';
            app.CZLabel = uilabel(app.TensorPanel);
            app.CZLabel.Text = 'Z';
            app.RXLabel = uilabel(app.TensorPanel);
            app.RXLabel.Text = 'X';
            app.RYLabel = uilabel(app.TensorPanel);
            app.RYLabel.Text = 'Y';
            app.RZLabel = uilabel(app.TensorPanel);
            app.RZLabel.Text = 'Z';
            app.XXField = uieditfield(app.TensorPanel, 'text');
            app.XXField.ValueChangedFcn = createCallbackFcn(app, @XXFieldValueChanged, true);
            app.XXField.Value = '0';
            app.XYField = uieditfield(app.TensorPanel, 'text');
            app.XYField.ValueChangedFcn = createCallbackFcn(app, @XYFieldValueChanged, true);
            app.XYField.Value = '0';
            app.XZField = uieditfield(app.TensorPanel, 'text');
            app.XZField.ValueChangedFcn = createCallbackFcn(app, @XZFieldValueChanged, true);
            app.XZField.Value = '0';
            app.YXField = uieditfield(app.TensorPanel, 'text');
            app.YXField.ValueChangedFcn = createCallbackFcn(app, @YXFieldValueChanged, true);
            app.YXField.Value = '0';
            app.YYField = uieditfield(app.TensorPanel, 'text');
            app.YYField.ValueChangedFcn = createCallbackFcn(app, @YYFieldValueChanged, true);
            app.YYField.Value = '0';
            app.YZField = uieditfield(app.TensorPanel, 'text');
            app.YZField.ValueChangedFcn = createCallbackFcn(app, @YZFieldValueChanged, true);
            app.YZField.Value = '0';
            app.ZXField = uieditfield(app.TensorPanel, 'text');
            app.ZXField.ValueChangedFcn = createCallbackFcn(app, @ZXFieldValueChanged, true);
            app.ZXField.Value = '0';
            app.ZYField = uieditfield(app.TensorPanel, 'text');
            app.ZYField.ValueChangedFcn = createCallbackFcn(app, @ZYFieldValueChanged, true);
            app.ZYField.Value = '0';
            app.ZZField = uieditfield(app.TensorPanel, 'text');
            app.ZZField.ValueChangedFcn = createCallbackFcn(app, @ZZFieldValueChanged, true);
            app.ZZField.Value = '0';
            % Show the figure after all components are created
            app.BoundaryTensorFormUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BoundaryTensorForm(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BoundaryTensorFormUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BoundaryTensorFormUIFigure)
        end
    end
end