classdef EigenModeModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EigenModeUIFigure          matlab.ui.Figure
        RowMainGrid                matlab.ui.container.GridLayout
        UpperRowGrid               matlab.ui.container.GridLayout
        ExitButton                 matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        ModuleName                 matlab.ui.control.Label
        ColumnMainGrid             matlab.ui.container.GridLayout
        RightPanelGrid             matlab.ui.container.GridLayout
        SolutionGrid               matlab.ui.container.GridLayout
        ExportPanel                matlab.ui.container.Panel
        ResultsPanel               matlab.ui.container.Panel
        ResultsGrid                matlab.ui.container.GridLayout
        ExportFieldsButton         matlab.ui.control.Button
        ExportAllButton            matlab.ui.control.Button
        GridLayout2_3              matlab.ui.container.GridLayout
        AxisLabel                  matlab.ui.control.Label
        VerticalPointsLabel        matlab.ui.control.Label
        HorizontalPointsLabel      matlab.ui.control.Label
        GridLayout2_2              matlab.ui.container.GridLayout
        PosLabel                   matlab.ui.control.Label
        PositionField              matlab.ui.control.NumericEditField
        Plot_Selection_Grid        matlab.ui.container.GridLayout
        PlotAxisSelection          matlab.ui.control.DropDown
        VNField                    matlab.ui.control.NumericEditField
        HNField                    matlab.ui.control.NumericEditField
        PlotSolutionButton         matlab.ui.control.Button
        ResSelectionGrid           matlab.ui.container.GridLayout
        UnitLabel_3                matlab.ui.control.Label
        ResSelection               matlab.ui.control.DropDown
        ResFreqGrid                matlab.ui.container.GridLayout
        UnitLabel_2                matlab.ui.control.Label
        ResFreq                    matlab.ui.control.DropDown
        SolutionPanel              matlab.ui.container.Panel
        SolutionSubGrid            matlab.ui.container.GridLayout
        ShiftWavenumberLabel       matlab.ui.control.Label
        ShiftField                 matlab.ui.control.NumericEditField
        EigenField                 matlab.ui.control.NumericEditField
        NumberofEigenvaluesLabel   matlab.ui.control.Label
        SolveButton                matlab.ui.control.Button
        SolFreqGrid                matlab.ui.container.GridLayout
        UnitLabel                  matlab.ui.control.Label
        SolutionFreqSelection      matlab.ui.control.DropDown
        BoundaryDisplayGrid        matlab.ui.container.GridLayout
        GridLayout                 matlab.ui.container.GridLayout
        TypeLabel                  matlab.ui.control.Label
        PositionLabel              matlab.ui.control.Label
        BoundaryLabel_2            matlab.ui.control.Label
        BoundariesTable            matlab.ui.control.Table
        LeftPanelGrid              matlab.ui.container.GridLayout
        PreprocessingGrid          matlab.ui.container.GridLayout
        ImportGeometryGrid         matlab.ui.container.GridLayout
        BoundariesTreePanel        matlab.ui.container.Panel
        BoundariesTreeGrid         matlab.ui.container.GridLayout
        BoundariesTree             matlab.ui.container.Tree
        DomainsTreePanel           matlab.ui.container.Panel
        DomainsTreeGrid            matlab.ui.container.GridLayout
        DomainsTree                matlab.ui.container.Tree
        ImportPanel                matlab.ui.container.Panel
        LoadGrid                   matlab.ui.container.GridLayout
        SaveModelButton            matlab.ui.control.Button
        LoadGeometryButton         matlab.ui.control.Button
        LoadSelection              matlab.ui.container.ButtonGroup
        ToolboxModelButton         matlab.ui.control.RadioButton
        MatlabMeshButton           matlab.ui.control.RadioButton
        ComsolMeshButton           matlab.ui.control.RadioButton
        AssemblyPanel              matlab.ui.container.Panel
        AssemblyGrid               matlab.ui.container.GridLayout
        EigenSelectionGrid         matlab.ui.container.GridLayout
        n_effLabel                 matlab.ui.control.Label
        NCheck                     matlab.ui.control.CheckBox
        KCheck                     matlab.ui.control.CheckBox
        AssemblyInfoPanel          matlab.ui.container.Panel
        AssemblyInfoGrid           matlab.ui.container.GridLayout
        N_Info                     matlab.ui.control.Label
        N_Label                    matlab.ui.control.Label
        NB_Info                    matlab.ui.control.Label
        NE_Info                    matlab.ui.control.Label
        NBLabel                    matlab.ui.control.Label
        NELabel                    matlab.ui.control.Label
        ExportMatricesButton       matlab.ui.control.Button
        PropagationAxisSelection   matlab.ui.control.DropDown
        MatrixSparsitiesButton     matlab.ui.control.Button
        AssemblyButton_2           matlab.ui.control.Button
        ScalingSelection           matlab.ui.control.DropDown
        ScalingLabel               matlab.ui.control.Label
        BoundariesPanel            matlab.ui.container.Panel
        BoundariesGrid             matlab.ui.container.GridLayout
        TensorCheckBox             matlab.ui.control.CheckBox
        BoundaryDispersiveCheck    matlab.ui.control.CheckBox
        BoundaryTensorButton       matlab.ui.control.Button
        BoundaryButtonGroup        matlab.ui.container.ButtonGroup
        PropagationConstantButton  matlab.ui.control.RadioButton
        WaveImpedanceButton        matlab.ui.control.RadioButton
        BoundaryDone               matlab.ui.control.Button
        BoundaryTypeSelection      matlab.ui.control.DropDown
        CurrentBoundaryGrid        matlab.ui.container.GridLayout
        CurrentBoundarySelection   matlab.ui.control.DropDown
        BoundaryLabel              matlab.ui.control.Label
        DomainsPanel               matlab.ui.container.Panel
        DomainPanelGrid            matlab.ui.container.GridLayout
        CurrentDomainDone_2        matlab.ui.control.Button
        EmptyDomainCheck           matlab.ui.control.CheckBox
        DomainDoneGrid             matlab.ui.container.GridLayout
        CurrentDomainDone          matlab.ui.control.Button
        DomainDoneButton           matlab.ui.control.Button
        TensorButtonGrid           matlab.ui.container.GridLayout
        ZiButton                   matlab.ui.control.Button
        KsiButton                  matlab.ui.control.Button
        MiButton                   matlab.ui.control.Button
        EpsilonButton              matlab.ui.control.Button
        BianisotropicCheckBox      matlab.ui.control.CheckBox
        DispersiveCheckBox         matlab.ui.control.CheckBox
        IsotropicCheckBox          matlab.ui.control.CheckBox
        MediumLabel                matlab.ui.control.Label
        MediumPanel                matlab.ui.container.Panel
        DomainSelectionGrid        matlab.ui.container.GridLayout
        DomainSelection            matlab.ui.control.DropDown
        DomainLabel                matlab.ui.control.Label
        FrequencyPanel             matlab.ui.container.Panel
        FrequencyGrid              matlab.ui.container.GridLayout
        VectorPanel                matlab.ui.container.Panel
        VectorGrid                 matlab.ui.container.GridLayout
        VectorField                matlab.ui.control.EditField
        SingleFrequencyPanel       matlab.ui.container.Panel
        SingleFrequencyGrid        matlab.ui.container.GridLayout
        SingleFrequencyField       matlab.ui.control.NumericEditField
        SingleFrequencyUnit        matlab.ui.control.DropDown
        StartStopPanel             matlab.ui.container.Panel
        StartStopGrid              matlab.ui.container.GridLayout
        StopF                      matlab.ui.control.NumericEditField
        StepF                      matlab.ui.control.NumericEditField
        StartF                     matlab.ui.control.NumericEditField
        StepLabel                  matlab.ui.control.Label
        StartLabel                 matlab.ui.control.Label
        StopLabel                  matlab.ui.control.Label
        MFPanel                    matlab.ui.container.Panel
        MFGrid                     matlab.ui.container.GridLayout
        MFButtonGroup              matlab.ui.container.ButtonGroup
        StartStepStopButton        matlab.ui.control.RadioButton
        VectorButton               matlab.ui.control.RadioButton
        MFUnit                     matlab.ui.control.DropDown
        FrequencyDoneButton        matlab.ui.control.Button
        SingleFrequencyCheckBox    matlab.ui.control.CheckBox
        StateGrid                  matlab.ui.container.GridLayout
        ConditionLabel             matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        TextArea                   matlab.ui.control.TextArea
        CentralGrid                matlab.ui.container.GridLayout
        PlotBoundaryPanel          matlab.ui.container.Panel
        PlotBoundaryGrid           matlab.ui.container.GridLayout
        ClearPlotBoundaryButton    matlab.ui.control.Button
        PlotBoundarySelection      matlab.ui.control.DropDown
        PlotBoundaryButton         matlab.ui.control.Button
        PlotMenuGrid               matlab.ui.container.GridLayout
        equalCheckBox              matlab.ui.control.CheckBox
        tightCheckBox              matlab.ui.control.CheckBox
        ClearButton                matlab.ui.control.Button
        PlotSelection              matlab.ui.control.DropDown
        PlotSelectionArea          matlab.ui.control.DropDown
        PlotButton                 matlab.ui.control.Button
        StateGrid_2                matlab.ui.container.GridLayout
        AssemblyButton             matlab.ui.control.Button
        BoundariesButton           matlab.ui.control.Button
        DomainsButton              matlab.ui.control.Button
        FrequencyButton            matlab.ui.control.Button
        GeometryButton             matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        TModel % ToolboxModel Object
        GeometryLoaded=false;% Completion of Geometry Loading Operation flag
        InitializationsComplete=false;% Completion of FEM Structure Initialization flag
        Occupied=false;% GUI Occupied Flag
        FrequencyMode=1;% 1 : single frequency mode;2 : multi frequency mode
        CurrentDomainIndex=1;% Selected Domain
        CurrentBoundaryIndex=1;% Selected Boundary
        DomainFlags;% Number of Domains x 1 vector of flags
        BoundaryFlags;% Number of Boundaries x 1 vector of flags
        Step=0;% 0 Geometry,1 Frequency,2 Domains,3 Boundaries,4 Assembly,5 Solution.
        BoundariesInfo;
        SolutionIndex=1;
        %-----------------------------------------------------------------
        Shift;
        NumberOfEigenValues;
        EigenValues;
        EigenVectors;
        EigenValueMode="k";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            EigenModeModule_StartUp(app);
        end

        % Button pushed function: LoadGeometryButton
        function LoadGeometryButtonPushed(app, event)
            if(app.GeometryLoaded==false)
                GUIActive(app,"Loading...");pause(0.1);
                ImportMesh_EigenModeModule(app);
                pause(0.1);
            elseif(app.GeometryLoaded==true && app.InitializationsComplete==false)
                GUIActive(app,"Initializing Element Structure...");pause(0.1);app.LoadGeometryButton.Enable=false;
                tic;app.TModel=InitializeElementStructure(app.TModel);ElementTime=toc;
                NewMessage(app,int2str(app.TModel.NumberOfElements)+" Elements Initialized ");
                NewMessage(app,"Time elapsed : " + num2str(ElementTime));
                GUIActive(app,"Element Structure Initialized");pause(0.1);
                %----------------------------------------------------------
                GUIActive(app,"Initializing Vertex Structure...");pause(0.1);
                tic;app.TModel=InitializeVertexStructure(app.TModel);VertexTime=toc;
                NewMessage(app,int2str(app.TModel.NumberOfVertices)+" Vertices Initialized ");
                NewMessage(app,int2str(app.TModel.NumberOfBoundaryVertices)+" Exterior Boundary Vertices found.");
                NewMessage(app,"Time elapsed : " + num2str(VertexTime));
                GUIActive(app,"Vertex Structure Initialized");pause(0.1);
                %----------------------------------------------------------
                GUIActive(app,"Initializing Edge Structure...");pause(0.1);
                tic;[app.TModel,NodeToNode]=InitializeEdgeStructure(app.TModel);EdgeTime=toc;
                NewMessage(app,int2str(app.TModel.NumberOfEdges)+" Edges Initialized ");
                NewMessage(app,"Time elapsed : " +num2str(EdgeTime));
                GUIActive(app,"Edge Structure Initialized");pause(0.1);
                %----------------------------------------------------------
                GUIActive(app,"Initializing Facet Structure...");pause(0.1);
                tic;app.TModel=InitializeFacetStructure(app.TModel,NodeToNode);FacetTime=toc;
                NewMessage(app,int2str(app.TModel.NumberOfFacets)+" Facets Initialized ");
                NewMessage(app,("Time elapsed : " +num2str(FacetTime)));
                GUIActive(app,"Facet Structure Initialized");pause(0.1);
                %----------------------------------------------------------
                %app.TModel=UpdateElementStructure(app.TModel);
                app.TModel = DetermineBoundaryGeometry(app.TModel);
                app.InitializationsComplete=true;app.LoadGeometryButton.Enable=true;
                InitializationsComplete_EigenModeModule(app);pause(0.1);
            else
                GeometryAndImportsDone_EigenModeModule(app);
            end
        end

        % Value changed function: SingleFrequencyCheckBox
        function SingleFrequencyCheckBoxValueChanged(app, event)
             value = app.SingleFrequencyCheckBox.Value;if(value),SingleFrequency_GUI(app);else,MultiFrequency_GUI(app);end 
        end

        % Selection changed function: MFButtonGroup
        function MFButtonGroupSelectionChanged(app, event)
              MultiFrequency_GUI(app);
        end

        % Value changed function: DomainSelection
        function DomainSelectionValueChanged(app, event)
            value = app.DomainSelection.Value;
            Index=value(8:end);app.CurrentDomainIndex=str2double(Index);
            app.MediumLabel.Text=app.TModel.Domains(app.CurrentDomainIndex).Medium.Tag;
            UpdateCurrentDomain(app);
        end

        % Button pushed function: FrequencyDoneButton
        function FrequencyDoneButtonPushed(app, event)
                FrequencySelectionDone_GUI(app);
        end

        % Value changed function: IsotropicCheckBox
        function IsotropicCheckBoxValueChanged(app, event)
            value = app.IsotropicCheckBox.Value;
            if(value)
                app.BianisotropicCheckBox.Value=false;
                app.TModel.Domains(app.CurrentDomainIndex).Medium=app.TModel.Domains(app.CurrentDomainIndex).Medium.Isotropic();
                app.KsiButton.Enable=false;app.KsiButton.Visible=false;
                app.ZiButton.Enable=false;app.ZiButton.Visible=false;
            else
                if(app.BianisotropicCheckBox.Value==false),app.TModel.Domains(app.CurrentDomainIndex).Medium=app.TModel.Domains(app.CurrentDomainIndex).Medium.Anisotropic();
                else
                end
            end
        end

        % Value changed function: DispersiveCheckBox
        function DispersiveCheckBoxValueChanged(app, event)
             value = app.DispersiveCheckBox.Value;
            if(value),app.TModel.Domains(app.CurrentDomainIndex).Medium=app.TModel.Domains(app.CurrentDomainIndex).Medium.Dispersive(app.TModel.Frequency);
            else,app.TModel.Domains(app.CurrentDomainIndex).Medium=app.TModel.Domains(app.CurrentDomainIndex).Medium.NoDispersive();
            end            
        end

        % Value changed function: BianisotropicCheckBox
        function BianisotropicCheckBoxValueChanged(app, event)
            value = app.BianisotropicCheckBox.Value;
            if(value),app.IsotropicCheckBox.Value=false;app.TModel.Domains(app.CurrentDomainIndex).Medium=app.TModel.Domains(app.CurrentDomainIndex).Medium.Bianisotropic();
                app.KsiButton.Enable=true;app.KsiButton.Visible=true;
                app.ZiButton.Enable=true;app.ZiButton.Visible=true;
            else
                app.KsiButton.Enable=false;app.KsiButton.Visible=false;
                app.ZiButton.Enable=false;app.ZiButton.Visible=false;
                if(app.IsotropicCheckBox.Value==false),app.TModel.Domains(app.CurrentDomainIndex).Medium=app.TModel.Domains(app.CurrentDomainIndex).Medium.Anisotropic();
                else,app.TModel.Domains(app.CurrentDomainIndex).Medium=app.TModel.Domains(app.CurrentDomainIndex).Medium.Isotropic();
                end
            end
        end

        % Button pushed function: EpsilonButton
        function EpsilonButtonPushed(app, event)
            TensorForm(app,1);
        end

        % Button pushed function: MiButton
        function MiButtonPushed(app, event)
            TensorForm(app,2);
        end

        % Button pushed function: KsiButton
        function KsiButtonPushed(app, event)
            TensorForm(app,4);
        end

        % Button pushed function: ZiButton
        function ZiButtonPushed(app, event)
            TensorForm(app,3);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: CurrentDomainDone
        function CurrentDomainDoneButtonPushed(app, event)
            app.DomainFlags(app.CurrentDomainIndex)=1;
            app.TModel.Domains(app.CurrentDomainIndex).Medium.CalculateMaterialParameters();
            NewMessage(app,"Medium for Domain " +num2str(app.CurrentDomainIndex) +" entered.")
            if(all(app.DomainFlags==1)),app.DomainDoneButton.Enable=true;end
        end

        % Button pushed function: DomainDoneButton
        function DomainDoneButtonPushed(app, event)
            DomainsDone_GUI(app);
        end

        % Value changed function: CurrentBoundarySelection
        function CurrentBoundarySelectionValueChanged(app, event)
            value = app.CurrentBoundarySelection.Value;
            Index=value(1:end);app.CurrentBoundaryIndex=str2double(Index);
            UpdateCurrentBoundary(app);
        end

        % Value changed function: BoundaryTypeSelection
        function BoundaryTypeSelectionValueChanged(app, event)
            BoundaryTypeSelection_EigenModeModule(app,app.BoundaryTypeSelection.Value);            
        end

        % Button pushed function: BoundaryTensorButton
        function BoundaryTensorButtonPushed(app, event)
            boundary=app.TModel.Boundaries(app.CurrentBoundaryIndex);
            if(boundary.Type=="IBC"),BoundaryTensorForm(app,4);
            elseif(boundary.Type=="ABB"),BoundaryTensorForm(app,1);
            elseif(boundary.Type=="ABZ"),BoundaryTensorForm(app,2);
            elseif(boundary.Type=="GRA"),BoundaryTensorForm(app,3);
            elseif(boundary.Type=="PBC"),BoundaryTensorForm(app,5);
            end
        end

        % Value changed function: BoundaryDispersiveCheck
        function BoundaryDispersiveCheckValueChanged(app, event)
                BoundaryDispersiveCheck_EigenModeModule(app);  
        end

        % Value changed function: TensorCheckBox
        function TensorCheckBoxValueChanged(app, event)
            value = app.TensorCheckBox.Value;
            if(value)
                if(app.BoundaryDispersiveCheck.Value)
                    app.TModel.Boundaries(app.CurrentBoundaryIndex)=app.TModel.Boundaries(app.CurrentBoundaryIndex).ABC_ZT(app.TModel.Frequency);
                    app.BoundariesInfo{app.CurrentBoundaryIndex,3}='ABZT';
                else,app.TModel.Boundaries(app.CurrentBoundaryIndex)=app.TModel.Boundaries(app.CurrentBoundaryIndex).ABC_ZT();
                    app.BoundariesInfo{app.CurrentBoundaryIndex,3}='ABZT';
                end
            else
                if(app.BoundaryDispersiveCheck.Value),app.TModel.Boundaries(app.CurrentBoundaryIndex)=app.TModel.Boundaries(app.CurrentBoundaryIndex).ABC_Z(app.TModel.Frequency);
                    app.BoundariesInfo{app.CurrentBoundaryIndex,3}='ABZ';
                else,app.TModel.Boundaries(app.CurrentBoundaryIndex)=app.TModel.Boundaries(app.CurrentBoundaryIndex).ABC_Z();
                    app.BoundariesInfo{app.CurrentBoundaryIndex,3}='ABZ';
                end
            end
            app.BoundariesTable.Data=app.BoundariesInfo;pause(0.1);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            GUIActive(app,"Saving...");SaveModel_GUI(app);GUIActive(app,"Model Saved");
        end

        % Button pushed function: PlotBoundaryButton
        function PlotBoundaryButtonPushed(app, event)
            indices=[];
            switch app.PlotBoundarySelection.ValueIndex
                case 1
                    for ii=1:numel(app.TModel.Boundaries),if(app.BoundaryFlags(ii)==0),indices(end+1)=ii;end,end
                    if(nnz(indices)~=0),GUIActive(app,"Plotting...");PlotBoundary_GUI(app,indices);GUIActive(app,"");end
                case 2
                    for ii=1:numel(app.TModel.Boundaries),if(app.TModel.Boundaries(ii).Type=="CON"),indices(end+1)=ii;end,end
                    if(nnz(indices)~=0),GUIActive(app,"Plotting...");PlotBoundary_GUI(app,indices);GUIActive(app,"");end
                case 3
                     for ii=1:numel(app.TModel.Boundaries),if(app.TModel.Boundaries(ii).Type=="PEC"),indices(end+1)=ii;end,end
                     if(nnz(indices)~=0),GUIActive(app,"Plotting...");PlotBoundary_GUI(app,indices);GUIActive(app,"");end
                case 4
                     for ii=1:numel(app.TModel.Boundaries),if(app.TModel.Boundaries(ii).Type=="PMC"),indices(end+1)=ii;end,end
                     if(nnz(indices)~=0),GUIActive(app,"Plotting...");PlotBoundary_GUI(app,indices);GUIActive(app,"");end
                case 5
                     for ii=1:numel(app.TModel.Boundaries),if(app.TModel.Boundaries(ii).Type=="IBC"),indices(end+1)=ii;end,end
                     if(nnz(indices)~=0),GUIActive(app,"Plotting...");PlotBoundary_GUI(app,indices);GUIActive(app,"");end
                case 6
                     for ii=1:numel(app.TModel.Boundaries),if(app.TModel.Boundaries(ii).Type=="PBC"),indices(end+1)=ii;end,end
                     if(nnz(indices)~=0),GUIActive(app,"Plotting...");PlotBoundary_GUI(app,indices);GUIActive(app,"");end
                case 7
                     for ii=1:numel(app.TModel.Boundaries),if(app.TModel.Boundaries(ii).Type=="ABC"),indices(end+1)=ii;end,end
                     if(nnz(indices)~=0),GUIActive(app,"Plotting...");PlotBoundary_GUI(app,indices);GUIActive(app,"");end
            end
        end

        % Button pushed function: BoundaryDone
        function BoundaryDoneButtonPushed(app, event)
            BoundariesDone_GUI(app);
        end

        % Button pushed function: GeometryButton
        function GeometryButtonPushed(app, event)
             SwitchGeometryPanel(app);
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
             SwitchFrequencyPanel(app);
        end

        % Button pushed function: DomainsButton
        function DomainsButtonPushed(app, event)
             SwitchDomainPanel(app);
        end

        % Button pushed function: BoundariesButton
        function BoundariesButtonPushed(app, event)
             SwitchBoundaryPanel(app);
        end

        % Button pushed function: AssemblyButton
        function AssemblyButtonPushed(app, event)
             SwitchAssemblyPanel(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            GUIActive(app,"Plotting...");pause(0.1);
            PlotFEMGeometryMesh(app);
            GUIActive(app,"");pause(0.1);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
             cla(app.UIAxes,'reset');
             switch app.Step
                case 0,PlotGeometry_GUI(app,0);
                case 1,PlotGeometry_GUI(app,3);
                case 2,PlotGeometry_GUI(app,1);
                case 3,PlotGeometry_GUI(app,2);
                case 4,PlotGeometry_GUI(app,0);
                case 5,PlotGeometry_GUI(app,0);
            end
        end

        % Button pushed function: AssemblyButton_2
        function AssemblyButton_2Pushed(app, event)
            GUIActive(app,"Enumerating Degrees of Freedom");pause(0.1);app.TModel=EnumerateDoFs_EB(app.TModel);GUIActive(app,"");pause(0.1);
            GUIActive(app,"Assembling Matrices...");pause(0.1);tic;Assembly_EigenModeModule(app);AssemblyTime=toc;GUIActive(app,"");pause(0.1);
            NewMessage(app,"Assembly Time : " + num2str(AssemblyTime) + " sec");
            AssemblyComplete_EigenModeModule(app);app.TModel.Cond=5;
            pause(0.1);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            GUIActive(app,"Solving..."),pause(0.1);
            Solve_EigenModeModule(app);
            GUIActive(app,"");pause(0.1);
            UpdateResultsPanel_EigenModeModule(app);
        end

        % Button pushed function: MatrixSparsitiesButton
        function MatrixSparsitiesButtonPushed(app, event)
            SpyMatrices(app.TModel);
        end

        % Button pushed function: PlotSolutionButton
        function PlotSolutionButtonPushed(app, event)
            GUIActive(app,"Plotting...");pause(0.1);PlotSolution_EigenModeModule(app);GUIActive(app,"");pause(0.1);
        end

        % Button pushed function: ExportFieldsButton
        function ExportFieldsButtonPushed(app, event)
            GUIActive(app,"Saving...");pause(0.1);SaveFields_EigenMode(app,0);GUIActive(app,"Saved");pause(0.1);
        end

        % Button pushed function: ExportAllButton
        function ExportAllButtonPushed(app, event)
            GUIActive(app,"Saving...");pause(0.1);SaveFields_EigenMode(app,1);GUIActive(app,"Saved");pause(0.1);
        end

        % Value changed function: SolutionFreqSelection
        function SolutionFreqSelectionValueChanged(app, event)
            app.SolutionIndex = app.SolutionFreqSelection.ValueIndex-1;           
        end

        % Value changed function: ResFreq
        function ResFreqValueChanged(app, event)
            Index = app.ResFreq.ValueIndex;app.ResSelection.Items={};
            for ii=1:numel(app.EigenValues{Index}),eigenValues=app.EigenValues{Index};app.ResSelection.Items{end+1}=num2str(eigenValues(ii));end
        end

        % Button pushed function: CurrentDomainDone_2
        function CurrentDomainDone_2ButtonPushed(app, event)
                LoadMedium_GUI(app);
        end

        % Button pushed function: ExportMatricesButton
        function ExportMatricesButtonPushed(app, event)
            ExportAssembledMatrices_EigenModeModule(app);
        end

        % Value changed function: EmptyDomainCheck
        function EmptyDomainCheckValueChanged(app, event)
            value = app.EmptyDomainCheck.Value;
            if(value),app.TModel.Domains(app.CurrentDomainIndex).IsEmpty=true;
            else,app.TModel.Domains(app.CurrentDomainIndex).IsEmpty=false;
            end
        end

        % Value changed function: KCheck
        function KCheckValueChanged(app, event)
            if(app.KCheck.Value),app.NCheck.Value=false;app.EigenValueMode="k";app.TModel.Assembled.EigenValue="k";
            end
            
        end

        % Value changed function: NCheck
        function NCheckValueChanged(app, event)
            if(app.NCheck.Value),app.EigenValueMode="n";app.KCheck.Value=false;app.TModel.Assembled.EigenValue="n";pause(0.1);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EigenModeUIFigure = uifigure('Visible', 'off');
            app.EigenModeUIFigure.Name = 'EB EigenMode Module';
            app.EigenModeUIFigure.Icon = 'Logo1.png';
            app.RowMainGrid = uigridlayout(app.EigenModeUIFigure);
            app.ColumnMainGrid = uigridlayout(app.RowMainGrid);
            app.CentralGrid = uigridlayout(app.ColumnMainGrid);
            app.UIAxes = uiaxes(app.CentralGrid);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.Image = uiimage(app.CentralGrid);
            app.Image.Enable = 'off';
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'Logo1.png';
            app.StateGrid_2 = uigridlayout(app.CentralGrid);
            app.GeometryButton = uibutton(app.StateGrid_2, 'push');
            app.GeometryButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryButtonPushed, true);
            app.GeometryButton.Enable = 'off';
            app.GeometryButton.Text = 'Geometry';
            app.FrequencyButton = uibutton(app.StateGrid_2, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Enable = 'off';
            app.FrequencyButton.Text = 'Frequency';
            app.DomainsButton = uibutton(app.StateGrid_2, 'push');
            app.DomainsButton.ButtonPushedFcn = createCallbackFcn(app, @DomainsButtonPushed, true);
            app.DomainsButton.Enable = 'off';
            app.DomainsButton.Text = 'Domains';
            app.BoundariesButton = uibutton(app.StateGrid_2, 'push');
            app.BoundariesButton.ButtonPushedFcn = createCallbackFcn(app, @BoundariesButtonPushed, true);
            app.BoundariesButton.Enable = 'off';
            app.BoundariesButton.Text = 'Boundaries';
            app.AssemblyButton = uibutton(app.StateGrid_2, 'push');
            app.AssemblyButton.ButtonPushedFcn = createCallbackFcn(app, @AssemblyButtonPushed, true);
            app.AssemblyButton.Enable = 'off';
            app.AssemblyButton.Text = 'Assembly';
            app.PlotMenuGrid = uigridlayout(app.CentralGrid);
            app.PlotButton = uibutton(app.PlotMenuGrid, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.PlotSelectionArea = uidropdown(app.PlotMenuGrid);
            app.PlotSelectionArea.Items = {};
            app.PlotSelectionArea.Enable = 'off';
            app.PlotSelectionArea.Value = {};
            app.PlotSelection = uidropdown(app.PlotMenuGrid);
            app.PlotSelection.Items = {'Vertices', 'Edges', 'Facets'};
            app.PlotSelection.Enable = 'off';
            app.PlotSelection.Value = 'Vertices';
            app.ClearButton = uibutton(app.PlotMenuGrid, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.tightCheckBox = uicheckbox(app.PlotMenuGrid);
            app.tightCheckBox.Text = 'tight';
            app.tightCheckBox.Value = true;
            app.equalCheckBox = uicheckbox(app.PlotMenuGrid);
            app.equalCheckBox.Text = 'equal';
            app.equalCheckBox.Value = true;
            app.PlotBoundaryPanel = uipanel(app.CentralGrid);
            app.PlotBoundaryGrid = uigridlayout(app.PlotBoundaryPanel);
            app.PlotBoundaryButton = uibutton(app.PlotBoundaryGrid, 'push');
            app.PlotBoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBoundaryButtonPushed, true);
            app.PlotBoundaryButton.Text = 'Plot';
            app.PlotBoundarySelection = uidropdown(app.PlotBoundaryGrid);
            app.PlotBoundarySelection.Items = {'Undefined', 'Continuity', 'Perfect Electric Conductor', 'Perfect Magnetic Conductor', 'Impedance Boundary Condition', 'Periodic Boundary Condition', 'Absorbing Boundary Condition'};
            app.PlotBoundarySelection.Value = 'Undefined';
            app.ClearPlotBoundaryButton = uibutton(app.PlotBoundaryGrid, 'push');
            app.ClearPlotBoundaryButton.Text = 'Clear';
            app.LeftPanelGrid = uigridlayout(app.ColumnMainGrid);
            app.TextArea = uitextarea(app.LeftPanelGrid);
            app.StateGrid = uigridlayout(app.LeftPanelGrid);
            app.Lamp = uilamp(app.StateGrid);
            app.ConditionLabel = uilabel(app.StateGrid);
            app.ConditionLabel.Text = '';
            app.PreprocessingGrid = uigridlayout(app.LeftPanelGrid);
            app.FrequencyPanel = uipanel(app.PreprocessingGrid);
            app.FrequencyPanel.Title = 'Frequency';
            app.FrequencyGrid = uigridlayout(app.FrequencyPanel);
            app.SingleFrequencyCheckBox = uicheckbox(app.FrequencyGrid);
            app.SingleFrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleFrequencyCheckBoxValueChanged, true);
            app.SingleFrequencyCheckBox.Text = 'Single Frequency';
            app.SingleFrequencyCheckBox.Value = true;
            app.FrequencyDoneButton = uibutton(app.FrequencyGrid, 'push');
            app.FrequencyDoneButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyDoneButtonPushed, true);
            app.FrequencyDoneButton.Text = 'Done';
            app.MFPanel = uipanel(app.FrequencyGrid);
            app.MFPanel.Enable = 'off';
            app.MFPanel.Visible = 'off';
            app.MFGrid = uigridlayout(app.MFPanel);
            app.MFUnit = uidropdown(app.MFGrid);
            app.MFUnit.Items = {'Hz', 'KHz', 'MHz', 'GHz', 'THz'};
            app.MFUnit.Value = 'Hz';
            app.MFButtonGroup = uibuttongroup(app.MFGrid);
            app.MFButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MFButtonGroupSelectionChanged, true);
            app.VectorButton = uiradiobutton(app.MFButtonGroup);
            app.VectorButton.Text = 'Vector';
            app.VectorButton.Value = true;
            app.StartStepStopButton = uiradiobutton(app.MFButtonGroup);
            app.StartStepStopButton.Text = 'Start:Step:Stop';
            app.StartStopPanel = uipanel(app.FrequencyGrid);
            app.StartStopPanel.Enable = 'off';
            app.StartStopPanel.Visible = 'off';
            app.StartStopGrid = uigridlayout(app.StartStopPanel);
            app.StopLabel = uilabel(app.StartStopGrid);
            app.StopLabel.Text = 'Stop : ';
            app.StartLabel = uilabel(app.StartStopGrid);
            app.StartLabel.Text = 'Start : ';
            app.StepLabel = uilabel(app.StartStopGrid);
            app.StepLabel.Text = 'Step : ';
            app.StartF = uieditfield(app.StartStopGrid, 'numeric');
            app.StartF.Limits = [0 Inf];
            app.StepF = uieditfield(app.StartStopGrid, 'numeric');
            app.StepF.Limits = [0 Inf];
            app.StopF = uieditfield(app.StartStopGrid, 'numeric');
            app.StopF.Limits = [0 Inf];
            app.SingleFrequencyPanel = uipanel(app.FrequencyGrid);
            app.SingleFrequencyGrid = uigridlayout(app.SingleFrequencyPanel);
            app.SingleFrequencyUnit = uidropdown(app.SingleFrequencyGrid);
            app.SingleFrequencyUnit.Items = {'Hz', 'KHz', 'MHz', 'GHz', 'THz'};
            app.SingleFrequencyUnit.Value = 'Hz';
            app.SingleFrequencyField = uieditfield(app.SingleFrequencyGrid, 'numeric');
            app.SingleFrequencyField.Limits = [0 Inf];
            app.VectorPanel = uipanel(app.FrequencyGrid);
            app.VectorPanel.Enable = 'off';
            app.VectorPanel.Visible = 'off';
            app.VectorGrid = uigridlayout(app.VectorPanel);
            app.VectorField = uieditfield(app.VectorGrid, 'text');
            app.VectorField.Placeholder = 'Use semicolon(;) separated valuies';
            app.DomainsPanel = uipanel(app.PreprocessingGrid);
            app.DomainsPanel.Title = 'Domains';
            app.DomainPanelGrid = uigridlayout(app.DomainsPanel);
            app.DomainSelectionGrid = uigridlayout(app.DomainPanelGrid);
            app.DomainLabel = uilabel(app.DomainSelectionGrid);
            app.DomainLabel.Text = 'Domain :';
            app.DomainSelection = uidropdown(app.DomainSelectionGrid);
            app.DomainSelection.Items = {};
            app.DomainSelection.ValueChangedFcn = createCallbackFcn(app, @DomainSelectionValueChanged, true);
            app.DomainSelection.Value = {};
            app.MediumPanel = uipanel(app.DomainPanelGrid);
            app.MediumLabel = uilabel(app.DomainPanelGrid);
            app.MediumLabel.Text = 'Medium 1';
            app.IsotropicCheckBox = uicheckbox(app.DomainPanelGrid);
            app.IsotropicCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsotropicCheckBoxValueChanged, true);
            app.IsotropicCheckBox.Text = 'Isotropic';
            app.DispersiveCheckBox = uicheckbox(app.DomainPanelGrid);
            app.DispersiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @DispersiveCheckBoxValueChanged, true);
            app.DispersiveCheckBox.Text = 'Dispersive';
            app.BianisotropicCheckBox = uicheckbox(app.DomainPanelGrid);
            app.BianisotropicCheckBox.ValueChangedFcn = createCallbackFcn(app, @BianisotropicCheckBoxValueChanged, true);
            app.BianisotropicCheckBox.Text = 'Bianisotropic';
            app.TensorButtonGrid = uigridlayout(app.DomainPanelGrid);
            app.EpsilonButton = uibutton(app.TensorButtonGrid, 'push');
            app.EpsilonButton.ButtonPushedFcn = createCallbackFcn(app, @EpsilonButtonPushed, true);
            app.EpsilonButton.Text = 'εr';
            app.MiButton = uibutton(app.TensorButtonGrid, 'push');
            app.MiButton.ButtonPushedFcn = createCallbackFcn(app, @MiButtonPushed, true);
            app.MiButton.Text = 'μr';
            app.KsiButton = uibutton(app.TensorButtonGrid, 'push');
            app.KsiButton.ButtonPushedFcn = createCallbackFcn(app, @KsiButtonPushed, true);
            app.KsiButton.Enable = 'off';
            app.KsiButton.Visible = 'off';
            app.KsiButton.Text = 'ξ';
            app.ZiButton = uibutton(app.TensorButtonGrid, 'push');
            app.ZiButton.ButtonPushedFcn = createCallbackFcn(app, @ZiButtonPushed, true);
            app.ZiButton.Enable = 'off';
            app.ZiButton.Visible = 'off';
            app.ZiButton.Text = 'ζ';
            app.DomainDoneGrid = uigridlayout(app.DomainPanelGrid);
            app.DomainDoneButton = uibutton(app.DomainDoneGrid, 'push');
            app.DomainDoneButton.ButtonPushedFcn = createCallbackFcn(app, @DomainDoneButtonPushed, true);
            app.DomainDoneButton.Enable = 'off';
            app.DomainDoneButton.Text = 'Done';
            app.CurrentDomainDone = uibutton(app.DomainDoneGrid, 'push');
            app.CurrentDomainDone.ButtonPushedFcn = createCallbackFcn(app, @CurrentDomainDoneButtonPushed, true);
            app.CurrentDomainDone.Text = 'Domain Done';
            app.EmptyDomainCheck = uicheckbox(app.DomainPanelGrid);
            app.EmptyDomainCheck.ValueChangedFcn = createCallbackFcn(app, @EmptyDomainCheckValueChanged, true);
            app.EmptyDomainCheck.Text = 'Empty';
            app.CurrentDomainDone_2 = uibutton(app.DomainPanelGrid, 'push');
            app.CurrentDomainDone_2.ButtonPushedFcn = createCallbackFcn(app, @CurrentDomainDone_2ButtonPushed, true);
            app.CurrentDomainDone_2.Text = 'Load Medium';
            app.BoundariesPanel = uipanel(app.PreprocessingGrid);
            app.BoundariesPanel.Title = 'Boundaries';
            app.BoundariesGrid = uigridlayout(app.BoundariesPanel);
            app.CurrentBoundaryGrid = uigridlayout(app.BoundariesGrid);
            app.BoundaryLabel = uilabel(app.CurrentBoundaryGrid);
            app.BoundaryLabel.Text = 'Boundary :';
            app.CurrentBoundarySelection = uidropdown(app.CurrentBoundaryGrid);
            app.CurrentBoundarySelection.Items = {};
            app.CurrentBoundarySelection.ValueChangedFcn = createCallbackFcn(app, @CurrentBoundarySelectionValueChanged, true);
            app.CurrentBoundarySelection.Value = {};
            app.BoundaryTypeSelection = uidropdown(app.BoundariesGrid);
            app.BoundaryTypeSelection.Items = {'Continuity', 'Perfect Electric Conductor', 'Perfect Magnetic Conductor', 'Impedance Boundary Condition', 'Absorbing Boundary Condition', 'Absorbing Bondary Condition - Impedance', 'Graphene', 'Periodic Boundary Condition'};
            app.BoundaryTypeSelection.ValueChangedFcn = createCallbackFcn(app, @BoundaryTypeSelectionValueChanged, true);
            app.BoundaryTypeSelection.Value = 'Continuity';
            app.BoundaryDone = uibutton(app.BoundariesGrid, 'push');
            app.BoundaryDone.ButtonPushedFcn = createCallbackFcn(app, @BoundaryDoneButtonPushed, true);
            app.BoundaryDone.Text = 'Done';
            app.BoundaryButtonGroup = uibuttongroup(app.BoundariesGrid);
            app.BoundaryButtonGroup.Enable = 'off';
            app.BoundaryButtonGroup.Visible = 'off';
            app.WaveImpedanceButton = uiradiobutton(app.BoundaryButtonGroup);
            app.WaveImpedanceButton.Text = 'Wave Impedance (Ζ)';
            app.WaveImpedanceButton.Value = true;
            app.PropagationConstantButton = uiradiobutton(app.BoundaryButtonGroup);
            app.PropagationConstantButton.Text = 'Propagation Constant (β)';
            app.BoundaryTensorButton = uibutton(app.BoundariesGrid, 'push');
            app.BoundaryTensorButton.ButtonPushedFcn = createCallbackFcn(app, @BoundaryTensorButtonPushed, true);
            app.BoundaryTensorButton.Enable = 'off';
            app.BoundaryTensorButton.Visible = 'off';
            app.BoundaryTensorButton.Text = 'Value';
            app.BoundaryDispersiveCheck = uicheckbox(app.BoundariesGrid);
            app.BoundaryDispersiveCheck.ValueChangedFcn = createCallbackFcn(app, @BoundaryDispersiveCheckValueChanged, true);
            app.BoundaryDispersiveCheck.Enable = 'off';
            app.BoundaryDispersiveCheck.Visible = 'off';
            app.BoundaryDispersiveCheck.Text = 'Dispersive';
            app.TensorCheckBox = uicheckbox(app.BoundariesGrid);
            app.TensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @TensorCheckBoxValueChanged, true);
            app.TensorCheckBox.Enable = 'off';
            app.TensorCheckBox.Visible = 'off';
            app.TensorCheckBox.Text = 'Tensor';
            app.AssemblyPanel = uipanel(app.PreprocessingGrid);
            app.AssemblyPanel.Title = 'Assembly';
            app.AssemblyGrid = uigridlayout(app.AssemblyPanel);
            app.ScalingLabel = uilabel(app.AssemblyGrid);
            app.ScalingLabel.Text = 'Scaling';
            app.ScalingSelection = uidropdown(app.AssemblyGrid);
            app.ScalingSelection.Items = {'Edge Length,Facet Surface', 'Edge Length', 'Facet Surface', 'none'};
            app.ScalingSelection.Value = 'Edge Length,Facet Surface';
            app.AssemblyButton_2 = uibutton(app.AssemblyGrid, 'push');
            app.AssemblyButton_2.ButtonPushedFcn = createCallbackFcn(app, @AssemblyButton_2Pushed, true);
            app.AssemblyButton_2.Text = 'Assembly';
            app.MatrixSparsitiesButton = uibutton(app.AssemblyGrid, 'push');
            app.MatrixSparsitiesButton.ButtonPushedFcn = createCallbackFcn(app, @MatrixSparsitiesButtonPushed, true);
            app.MatrixSparsitiesButton.Enable = 'off';
            app.MatrixSparsitiesButton.Text = 'Matrix Sparsities';
            app.PropagationAxisSelection = uidropdown(app.AssemblyGrid);
            app.PropagationAxisSelection.Items = {'x Propagation', 'y Propagation', 'z Propagation'};
            app.PropagationAxisSelection.Value = 'x Propagation';
            app.ExportMatricesButton = uibutton(app.AssemblyGrid, 'push');
            app.ExportMatricesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMatricesButtonPushed, true);
            app.ExportMatricesButton.Enable = 'off';
            app.ExportMatricesButton.Text = 'Export Matrices';
            app.AssemblyInfoPanel = uipanel(app.AssemblyGrid);
            app.AssemblyInfoGrid = uigridlayout(app.AssemblyInfoPanel);
            app.NELabel = uilabel(app.AssemblyInfoGrid);
            app.NELabel.Text = {'Number of'; ' Electric DoFs :'};
            app.NBLabel = uilabel(app.AssemblyInfoGrid);
            app.NBLabel.Text = {'Number of'; 'Magnetic DoFs :'};
            app.NE_Info = uilabel(app.AssemblyInfoGrid);
            app.NE_Info.Text = '';
            app.NB_Info = uilabel(app.AssemblyInfoGrid);
            app.NB_Info.Text = '';
            app.N_Label = uilabel(app.AssemblyInfoGrid);
            app.N_Label.Text = 'Total DoFs :';
            app.N_Info = uilabel(app.AssemblyInfoGrid);
            app.N_Info.Text = '';
            app.EigenSelectionGrid = uigridlayout(app.AssemblyGrid);
            app.KCheck = uicheckbox(app.EigenSelectionGrid);
            app.KCheck.ValueChangedFcn = createCallbackFcn(app, @KCheckValueChanged, true);
            app.KCheck.Text = '    k';
            app.KCheck.Value = true;
            app.NCheck = uicheckbox(app.EigenSelectionGrid);
            app.NCheck.ValueChangedFcn = createCallbackFcn(app, @NCheckValueChanged, true);
            app.NCheck.Text = '';
            app.n_effLabel = uilabel(app.EigenSelectionGrid);
            app.n_effLabel.Interpreter = 'tex';
            app.n_effLabel.Text = 'n_{eff}';
            app.ImportGeometryGrid = uigridlayout(app.PreprocessingGrid);
            app.ImportPanel = uipanel(app.ImportGeometryGrid);
            app.ImportPanel.Title = 'Import';
            app.LoadGrid = uigridlayout(app.ImportPanel);
            app.LoadSelection = uibuttongroup(app.LoadGrid);
            app.ComsolMeshButton = uiradiobutton(app.LoadSelection);
            app.ComsolMeshButton.Text = ' Mesh';
            app.ComsolMeshButton.Value = true;
            app.MatlabMeshButton = uiradiobutton(app.LoadSelection);
            app.MatlabMeshButton.Enable = 'off';
            app.MatlabMeshButton.Visible = 'off';
            app.MatlabMeshButton.Text = 'Matlab Mesh';
            app.ToolboxModelButton = uiradiobutton(app.LoadSelection);
            app.ToolboxModelButton.Text = 'Toolbox Model';
            app.LoadGeometryButton = uibutton(app.LoadGrid, 'push');
            app.LoadGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGeometryButtonPushed, true);
            app.LoadGeometryButton.Text = 'Load';
            app.SaveModelButton = uibutton(app.LoadGrid, 'push');
            app.SaveModelButton.Enable = 'off';
            app.SaveModelButton.Visible = 'off';
            app.SaveModelButton.Text = 'Save Model';
            app.DomainsTreePanel = uipanel(app.ImportGeometryGrid);
            app.DomainsTreePanel.Enable = 'off';
            app.DomainsTreePanel.Title = 'Domains';
            app.DomainsTreePanel.Visible = 'off';
            app.DomainsTreeGrid = uigridlayout(app.DomainsTreePanel);
            app.DomainsTree = uitree(app.DomainsTreeGrid);
            app.BoundariesTreePanel = uipanel(app.ImportGeometryGrid);
            app.BoundariesTreePanel.Enable = 'off';
            app.BoundariesTreePanel.Title = 'Boundaries';
            app.BoundariesTreePanel.Visible = 'off';
            app.BoundariesTreeGrid = uigridlayout(app.BoundariesTreePanel);
            app.BoundariesTree = uitree(app.BoundariesTreeGrid);
            app.RightPanelGrid = uigridlayout(app.ColumnMainGrid);
            app.BoundaryDisplayGrid = uigridlayout(app.RightPanelGrid);
            app.BoundariesTable = uitable(app.BoundaryDisplayGrid);
            app.BoundariesTable.ColumnName = '';
            app.BoundariesTable.RowName = {};
            app.GridLayout = uigridlayout(app.BoundaryDisplayGrid);
            app.BoundaryLabel_2 = uilabel(app.GridLayout);
            app.BoundaryLabel_2.Text = 'Boundary';
            app.PositionLabel = uilabel(app.GridLayout);
            app.PositionLabel.Text = 'Position';
            app.TypeLabel = uilabel(app.GridLayout);
            app.TypeLabel.Text = 'Type';
            app.SolutionGrid = uigridlayout(app.RightPanelGrid);
            app.SolutionPanel = uipanel(app.SolutionGrid);
            app.SolutionPanel.Title = 'Solution';
            app.SolutionSubGrid = uigridlayout(app.SolutionPanel);
            app.SolFreqGrid = uigridlayout(app.SolutionSubGrid);
            app.SolutionFreqSelection = uidropdown(app.SolFreqGrid);
            app.SolutionFreqSelection.Items = {};
            app.SolutionFreqSelection.ValueChangedFcn = createCallbackFcn(app, @SolutionFreqSelectionValueChanged, true);
            app.SolutionFreqSelection.Value = {};
            app.UnitLabel = uilabel(app.SolFreqGrid);
            app.UnitLabel.Text = 'GHz';
            app.SolveButton = uibutton(app.SolutionSubGrid, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.NumberofEigenvaluesLabel = uilabel(app.SolutionSubGrid);
            app.NumberofEigenvaluesLabel.Text = 'Number of Eigenvalues';
            app.EigenField = uieditfield(app.SolutionSubGrid, 'numeric');
            app.EigenField.Limits = [0 Inf];
            app.EigenField.Value = 5;
            app.ShiftField = uieditfield(app.SolutionSubGrid, 'numeric');
            app.ShiftField.Limits = [0 Inf];
            app.ShiftWavenumberLabel = uilabel(app.SolutionSubGrid);
            app.ShiftWavenumberLabel.Text = 'Shift (Wavenumber)';
            app.ResultsPanel = uipanel(app.SolutionGrid);
            app.ResultsPanel.Title = 'Results';
            app.ResultsGrid = uigridlayout(app.ResultsPanel);
            app.ResFreqGrid = uigridlayout(app.ResultsGrid);
            app.ResFreq = uidropdown(app.ResFreqGrid);
            app.ResFreq.Items = {};
            app.ResFreq.ValueChangedFcn = createCallbackFcn(app, @ResFreqValueChanged, true);
            app.ResFreq.Value = {};
            app.UnitLabel_2 = uilabel(app.ResFreqGrid);
            app.UnitLabel_2.Text = 'GHz';
            app.ResSelectionGrid = uigridlayout(app.ResultsGrid);
            app.ResSelection = uidropdown(app.ResSelectionGrid);
            app.ResSelection.Items = {};
            app.ResSelection.Value = {};
            app.UnitLabel_3 = uilabel(app.ResSelectionGrid);
            app.UnitLabel_3.Text = 'k(ω)';
            app.PlotSolutionButton = uibutton(app.ResultsGrid, 'push');
            app.PlotSolutionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSolutionButtonPushed, true);
            app.PlotSolutionButton.Text = 'Plot Slice';
            app.Plot_Selection_Grid = uigridlayout(app.ResultsGrid);
            app.HNField = uieditfield(app.Plot_Selection_Grid, 'numeric');
            app.HNField.Limits = [0 Inf];
            app.HNField.Value = 100;
            app.VNField = uieditfield(app.Plot_Selection_Grid, 'numeric');
            app.VNField.Limits = [0 Inf];
            app.VNField.Value = 100;
            app.PlotAxisSelection = uidropdown(app.Plot_Selection_Grid);
            app.PlotAxisSelection.Items = {'x', 'y', 'z'};
            app.PlotAxisSelection.Value = 'x';
            app.GridLayout2_2 = uigridlayout(app.ResultsGrid);
            app.PositionField = uieditfield(app.GridLayout2_2, 'numeric');
            app.PositionField.Limits = [0 Inf];
            app.PosLabel = uilabel(app.GridLayout2_2);
            app.PosLabel.Text = 'Position';
            app.GridLayout2_3 = uigridlayout(app.ResultsGrid);
            app.HorizontalPointsLabel = uilabel(app.GridLayout2_3);
            app.HorizontalPointsLabel.Text = {'Horizontal'; 'Points'};
            app.VerticalPointsLabel = uilabel(app.GridLayout2_3);
            app.VerticalPointsLabel.Text = {'Vertical '; 'Points'};
            app.AxisLabel = uilabel(app.GridLayout2_3);
            app.AxisLabel.Text = 'Axis';
            app.ExportAllButton = uibutton(app.ResultsGrid, 'push');
            app.ExportAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAllButtonPushed, true);
            app.ExportAllButton.Text = 'Export All';
            app.ExportFieldsButton = uibutton(app.ResultsGrid, 'push');
            app.ExportFieldsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFieldsButtonPushed, true);
            app.ExportFieldsButton.Text = 'Export Fields';
            app.ExportPanel = uipanel(app.SolutionGrid);
            app.ExportPanel.Title = 'Export';
            app.UpperRowGrid = uigridlayout(app.RowMainGrid);
            app.ModuleName = uilabel(app.UpperRowGrid);
            app.ModuleName.Text = 'EB EigenMode Module';
            app.SaveButton = uibutton(app.UpperRowGrid, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.ExitButton = uibutton(app.UpperRowGrid, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.EigenModeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EigenModeModule

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EigenModeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EigenModeUIFigure)
        end
    end
end