classdef TensorForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DomainTensorFormUIFigure  matlab.ui.Figure
        MainGrid                  matlab.ui.container.GridLayout
        ComplexExp                matlab.ui.control.Label
        NameGrid                  matlab.ui.container.GridLayout
        NameField                 matlab.ui.control.EditField
        NameLabel                 matlab.ui.control.Label
        MFPanel                   matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        UnitLabel                 matlab.ui.control.Label
        FrequencyLabel            matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        ExplainLabel              matlab.ui.control.Label
        TensorPanel               matlab.ui.container.GridLayout
        ZZField                   matlab.ui.control.EditField
        ZYField                   matlab.ui.control.EditField
        ZXField                   matlab.ui.control.EditField
        YZField                   matlab.ui.control.EditField
        YYField                   matlab.ui.control.EditField
        YXField                   matlab.ui.control.EditField
        XZField                   matlab.ui.control.EditField
        XYField                   matlab.ui.control.EditField
        XXField                   matlab.ui.control.EditField
        RZLabel                   matlab.ui.control.Label
        RYLabel                   matlab.ui.control.Label
        RXLabel                   matlab.ui.control.Label
        CZLabel                   matlab.ui.control.Label
        CYLabel                   matlab.ui.control.Label
        CXLabel                   matlab.ui.control.Label
        GridLayout3               matlab.ui.container.GridLayout
        DoneButton                matlab.ui.control.Button
        CButton                   matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        DefineButton              matlab.ui.control.Button
        TitleLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        Input;
        MasterApp;
        % Description
    end
    
    properties (Access = public)
        MultiF=[];
        masterApp;
        medium;
        type;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MasterApp, Input)
            app.masterApp=MasterApp;app.type=Input;app.medium=MasterApp.TModel.Domains(MasterApp.CurrentDomainIndex).Medium;InitializeTensorForm(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if(~isempty(app.NameField.Value)),app.medium.Tag=app.NameField.Value;end
            app.masterApp.TModel.Domains(app.masterApp.CurrentDomainIndex).Medium=app.medium;
            app.masterApp.MediumLabel.Text=app.medium.Tag;
            app.delete;
        end

        % Button pushed function: DefineButton
        function DefineButtonPushed(app, event)
            if(app.medium.IsDispersive)
                index=app.DropDown.ValueIndex;
                DefineMaterial(app,index);
            else
                DefineMaterial(app);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            index=app.DropDown.ValueIndex;
            UpdateTensorForm(app,index);
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.XXField.Value="0";app.XYField.Value="0";app.XZField.Value="0";
            app.YXField.Value="0";app.YYField.Value="0";app.YZField.Value="0";
            app.ZXField.Value="0";app.ZYField.Value="0";app.ZZField.Value="0";
        end

        % Value changed function: XXField
        function XXFieldValueChanged(app, event)
            if(isnan(String2Complex(app.XXField.Value))),set(app.XXField,"Value","0");end
        end

        % Value changed function: XYField
        function XYFieldValueChanged(app, event)
            if(isnan(String2Complex(app.XYField.Value))),set(app.XYField,"Value","0");end            
        end

        % Value changed function: XZField
        function XZFieldValueChanged(app, event)
           if(isnan(String2Complex(app.XZField.Value))),set(app.XZField,"Value","0");end            
        end

        % Value changed function: YXField
        function YXFieldValueChanged(app, event)
            if(isnan(String2Complex(app.YXField.Value))),set(app.YXField,"Value","0");end            
        end

        % Value changed function: YYField
        function YYFieldValueChanged(app, event)
            if(isnan(String2Complex(app.YYField.Value))),set(app.YYField,"Value","0");end            
        end

        % Value changed function: YZField
        function YZFieldValueChanged(app, event)
            if(isnan(String2Complex(app.YZField.Value))),set(app.YZField,"Value","0");end            
        end

        % Value changed function: ZXField
        function ZXFieldValueChanged(app, event)
            if(isnan(String2Complex(app.ZXField.Value))),set(app.ZXField,"Value","0");end            
        end

        % Value changed function: ZYField
        function ZYFieldValueChanged(app, event)
           if(isnan(String2Complex(app.ZYField.Value))),set(app.ZYField,"Value","0");end            
        end

        % Value changed function: ZZField
        function ZZFieldValueChanged(app, event)
            if(isnan(String2Complex(app.ZZField.Value))),set(app.ZZField,"Value","0");end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DomainTensorFormUIFigure = uifigure('Visible', 'off');
            app.DomainTensorFormUIFigure.Name = 'Domain Tensor Form';
            app.MainGrid = uigridlayout(app.DomainTensorFormUIFigure);
            app.TitleLabel = uilabel(app.MainGrid);
            app.TitleLabel.Interpreter = 'tex';
            app.TitleLabel.Text = 'Dielectric Permittivity  ε_{r}';
            app.GridLayout3 = uigridlayout(app.MainGrid);
            app.DefineButton = uibutton(app.GridLayout3, 'push');
            app.DefineButton.ButtonPushedFcn = createCallbackFcn(app, @DefineButtonPushed, true);
            app.DefineButton.Text = 'Define';
            app.CancelButton = uibutton(app.GridLayout3, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CButton = uibutton(app.GridLayout3, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.DoneButton = uibutton(app.GridLayout3, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.TensorPanel = uigridlayout(app.MainGrid);
            app.CXLabel = uilabel(app.TensorPanel);
            app.CXLabel.Text = 'X';
            app.CYLabel = uilabel(app.TensorPanel);
            app.CYLabel.Text = 'Y';
            app.CZLabel = uilabel(app.TensorPanel);
            app.CZLabel.Text = 'Z';
            app.RXLabel = uilabel(app.TensorPanel);
            app.RXLabel.Text = 'X';
            app.RYLabel = uilabel(app.TensorPanel);
            app.RYLabel.Text = 'Y';
            app.RZLabel = uilabel(app.TensorPanel);
            app.RZLabel.Text = 'Z';
            app.XXField = uieditfield(app.TensorPanel, 'text');
            app.XXField.ValueChangedFcn = createCallbackFcn(app, @XXFieldValueChanged, true);
            app.XYField = uieditfield(app.TensorPanel, 'text');
            app.XYField.ValueChangedFcn = createCallbackFcn(app, @XYFieldValueChanged, true);
            app.XZField = uieditfield(app.TensorPanel, 'text');
            app.XZField.ValueChangedFcn = createCallbackFcn(app, @XZFieldValueChanged, true);
            app.YXField = uieditfield(app.TensorPanel, 'text');
            app.YXField.ValueChangedFcn = createCallbackFcn(app, @YXFieldValueChanged, true);
            app.YYField = uieditfield(app.TensorPanel, 'text');
            app.YYField.ValueChangedFcn = createCallbackFcn(app, @YYFieldValueChanged, true);
            app.YZField = uieditfield(app.TensorPanel, 'text');
            app.YZField.ValueChangedFcn = createCallbackFcn(app, @YZFieldValueChanged, true);
            app.ZXField = uieditfield(app.TensorPanel, 'text');
            app.ZXField.ValueChangedFcn = createCallbackFcn(app, @ZXFieldValueChanged, true);
            app.ZYField = uieditfield(app.TensorPanel, 'text');
            app.ZYField.ValueChangedFcn = createCallbackFcn(app, @ZYFieldValueChanged, true);
            app.ZZField = uieditfield(app.TensorPanel, 'text');
            app.ZZField.ValueChangedFcn = createCallbackFcn(app, @ZZFieldValueChanged, true);
            app.ExplainLabel = uilabel(app.MainGrid);
            app.ExplainLabel.Interpreter = 'latex';
            app.ExplainLabel.Text = '\textbf{Β}=μ_{0}μ_{r}\textbf{Η}+ζ\textbf{Ε}';
            app.MFPanel = uipanel(app.MainGrid);
            app.GridLayout4 = uigridlayout(app.MFPanel);
            app.DropDown = uidropdown(app.GridLayout4);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.FrequencyLabel = uilabel(app.GridLayout4);
            app.FrequencyLabel.Text = 'Frequency :';
            app.UnitLabel = uilabel(app.GridLayout4);
            app.UnitLabel.Text = 'GHz';
            app.NameGrid = uigridlayout(app.MainGrid);
            app.NameLabel = uilabel(app.NameGrid);
            app.NameLabel.Text = 'Name :';
            app.NameField = uieditfield(app.NameGrid, 'text');
            app.ComplexExp = uilabel(app.MainGrid);
            app.ComplexExp.Text = '(insert complex entries as 1+1i)';
            % Show the figure after all components are created
            app.DomainTensorFormUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TensorForm(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DomainTensorFormUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DomainTensorFormUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DomainTensorFormUIFigure)
        end
    end
end