classdef TwoDModalSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EH2D12EigenModeSolverUIFigure  matlab.ui.Figure
        MainGrid                       matlab.ui.container.GridLayout
        LeftMenuGrid                   matlab.ui.container.GridLayout
        LeftSubGrid                    matlab.ui.container.GridLayout
        SolutionPanel                  matlab.ui.container.Panel
        SolutionGrid                   matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        NeffCheck                      matlab.ui.control.CheckBox
        BetaCheck                      matlab.ui.control.CheckBox
        GridLayout4_2                  matlab.ui.container.GridLayout
        FreqUnit_2                     matlab.ui.control.Label
        SolFreq                        matlab.ui.control.DropDown
        SolveButton                    matlab.ui.control.Button
        ShiftLabel                     matlab.ui.control.Label
        NumberOfEigenValuesLabel       matlab.ui.control.Label
        ShiftField                     matlab.ui.control.NumericEditField
        NEigenValueField               matlab.ui.control.NumericEditField
        AssemblyPanel                  matlab.ui.container.Panel
        AssemblyGrid                   matlab.ui.container.GridLayout
        EquationLabel_2                matlab.ui.control.Label
        AssemblyButton                 matlab.ui.control.Button
        EquationLabel_1                matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        ConditionGrid                  matlab.ui.container.GridLayout
        ConditionLabel                 matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        RightGrid                      matlab.ui.container.GridLayout
        BoundariesAndDomainsGrid       matlab.ui.container.GridLayout
        ResultsPanel                   matlab.ui.container.Panel
        ResultsGrid                    matlab.ui.container.GridLayout
        DoneButton                     matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        FreqUnit                       matlab.ui.control.Label
        FreqSelection                  matlab.ui.control.DropDown
        Plot_Selection_Grid            matlab.ui.container.GridLayout
        VNField                        matlab.ui.control.NumericEditField
        HNField                        matlab.ui.control.NumericEditField
        GridLayout2                    matlab.ui.container.GridLayout
        VerticalPointsLabel            matlab.ui.control.Label
        HorizontalPointsLabel          matlab.ui.control.Label
        ResSelectionGrid               matlab.ui.container.GridLayout
        UnitLabel                      matlab.ui.control.Label
        ResSelection                   matlab.ui.control.DropDown
        LineBoundariesPanel            matlab.ui.container.Panel
        BoundariesGrid                 matlab.ui.container.GridLayout
        BoundaryDefine                 matlab.ui.control.Button
        BoundaryTypeGrid               matlab.ui.container.GridLayout
        BoundaryTypeButton             matlab.ui.control.Button
        BoundaryTypePanel              matlab.ui.container.Panel
        BoundaryTypeSelection          matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        BoundariesSelectionPanel       matlab.ui.container.Panel
        BoundarySelection              matlab.ui.control.DropDown
        BoundaryDropDownLabel          matlab.ui.control.Label
        GeometryPanel                  matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mainApp;Type;
        TModel;BoundaryIndices;LineBoundaryIndices; % Description
        selectedLine=1;
        Equation=1;% 1 TFLF,2 TFTF,3 VWE
        NumberOfEigenValues;EigenValueShift;
        EigenValues;EigenVectors;
        AssembledSystem;Excitation_Res;
        Occupied=false;
        eigenvalues_selected;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp=mainApp;app.TModel=mainApp.TModel;
            TwoDModalSolver_StartUp(app);pause(0.1);
            app.Equation=1;
        end

        % Value changed function: BoundarySelection
        function BoundarySelectionValueChanged(app, event)
            value = app.BoundarySelection.Value;app.selectedLine=str2double(value);UpdateLineBoundary_GUI(app);
        end

        % Value changed function: BoundaryTypeSelection
        function BoundaryTypeSelectionValueChanged(app, event)
            value = app.BoundaryTypeSelection.Value;line=app.LineBoundaryIndices(app.selectedLine);
            if(value=="PEC"),app.BoundaryTypeButton.Enable=false;app.BoundaryTypeButton.Visible=false;
                  app.TModel.LineBoundaries(line)=app.TModel.LineBoundaries(line).PEC();
              
            elseif(value=="PMC"),app.BoundaryTypeButton.Enable=false;app.BoundaryTypeButton.Visible=false;
                                 app.TModel.LineBoundaries(line)=app.TModel.LineBoundaries(line).PMC();
            elseif(value=="Continuity"),app.BoundaryTypeButton.Enable=false;app.BoundaryTypeButton.Visible=false;
                                        app.TModel.LineBoundaries(line)=app.TModel.LineBoundaries(line).CON();
            elseif(value=="ABC"),app.BoundaryTypeButton.Enable=false;app.BoundaryTypeButton.Visible=false;
                                 app.TModel.LineBoundaries(line)=app.TModel.LineBoundaries(line).ABC();
                                 %app.BoundaryTypeButton.Enable=false;app.BoundaryTypeButton.Visible=false;
                                 %app.TModel.LineBoundaries(line)=app.TModel.LineBoundaries(line).PEC();
            elseif(value=="Graphene"),app.BoundaryTypeButton.Enable=true;app.BoundaryTypeButton.Visible=true;app.BoundaryTypeButton.Text="σ";
                                      if(app.mainApp.TModel.Frequency.NF==1),app.TModel.LineBoundaries(line)=app.TModel.LineBoundaries(line).Graphene();
                                      else,app.TModel.LineBoundaries(line)=app.TModel.LineBoundaries(line).Graphene(app.mainApp.TModel.Frequency);
                                      end           
            end
        end

        % Button pushed function: BoundaryDefine
        function BoundaryDefineButtonPushed(app, event)
              line=app.LineBoundaryIndices(app.selectedLine);
              app.mainApp.TModel.LineBoundaries(line)=app.TModel.LineBoundaries(line);
        end

        % Button pushed function: BoundaryTypeButton
        function BoundaryTypeButtonPushed(app, event)
                BoundaryTensorForm(app,6);
        end

        % Button pushed function: AssemblyButton
        function AssemblyButtonPushed(app, event)
            GUIActive(app,"Enumerating Degrees of Freedom...");pause(0.1);
            %app.Equation=2;
            switch app.Equation
                case 1,[app.TModel,app.Excitation_Res.Assembly]=EnumerateDoFs_TFLF(app.TModel,app.Excitation_Res.Assembly,app.Excitation_Res);
                case 2,[app.TModel,app.Excitation_Res.Assembly]=EnumerateDoFs_TFTF(app.TModel,app.Excitation_Res.Assembly,app.BoundaryIndices);
                case 3,[app.TModel,app.Excitation_Res.Assembly]=EnumerateDoFs_BVWE(app.TModel,app.Excitation_Res.Assembly,app.BoundaryIndices);
            end
            GUIActive(app,"");GUIActive(app,"Assembling...");pause(0.1);
            switch app.Equation
                case 1,tic;app.Excitation_Res.Assembly=TFLF_Assembly(app.TModel,app.Excitation_Res.Assembly,app.BoundaryIndices);AssTime=toc;
                case 2,tic;app.Excitation_Res.Assembly=TFTF_Assembly(app.TModel,app.Excitation_Res.Assembly,app.BoundaryIndices);AssTime=toc;
                case 3,tic;app.Excitation_Res.Assembly=VWE_Assembly(app.TModel,app.Excitation_Res.Assembly,app.BoundaryIndices);AssTime=toc;
            end
            NewMessage(app,"Assembly Time " + num2str(AssTime));GUIActive(app,"");pause(0.1);
            AssemblyDone_TwoDModalSolver(app);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            GUIActive(app,"Solving...");
            Solve_2DModalSolver(app);
            UpdateResultsPanel_TwoDModalSolver(app);    
            GUIActive(app,"");pause(0.1);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            GUIActive(app,"Plotting...");PlotFields_2DModalSolver(app);GUIActive(app,"");
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            SelectEigenvalue_2DModalSolver(app);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            Exit_2DModalSolver(app);
            app.delete;
        end

        % Value changed function: FreqSelection
        function FreqSelectionValueChanged(app, event)
            value = app.FreqSelection.ValueIndex;eigenValues=app.EigenValues{value};app.ResSelection.Items={};
            for ii=1:numel(eigenValues)
                app.ResSelection.Items{end+1}=num2str(eigenValues(ii));
            end
        end

        % Value changed function: BetaCheck
        function BetaCheckValueChanged(app, event)
            value = app.BetaCheck.Value;
            if(value),app.NeffCheck.Value=false;else,app.NeffCheck.Value=true;end,pause(0.1);
        end

        % Value changed function: NeffCheck
        function NeffCheckValueChanged(app, event)
            value = app.NeffCheck.Value;
            if(value),app.BetaCheck.Value=false;else,app.BetaCheck.Value=true;end,pause(0.1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EH2D12EigenModeSolverUIFigure = uifigure('Visible', 'off');
            app.EH2D12EigenModeSolverUIFigure.Name = 'EH 2D 1/2 EigenMode Solver';
            app.EH2D12EigenModeSolverUIFigure.Icon = 'Logo1.png';
            app.MainGrid = uigridlayout(app.EH2D12EigenModeSolverUIFigure);
            app.RightGrid = uigridlayout(app.MainGrid);
            app.GeometryPanel = uipanel(app.RightGrid);
            app.GeometryPanel.Title = 'Geometry';
            app.GridLayout = uigridlayout(app.GeometryPanel);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BoundariesAndDomainsGrid = uigridlayout(app.RightGrid);
            app.LineBoundariesPanel = uipanel(app.BoundariesAndDomainsGrid);
            app.LineBoundariesPanel.Title = 'Line Boundaries';
            app.BoundariesGrid = uigridlayout(app.LineBoundariesPanel);
            app.BoundariesSelectionPanel = uipanel(app.BoundariesGrid);
            app.BoundaryDropDownLabel = uilabel(app.BoundariesSelectionPanel);
            app.BoundaryDropDownLabel.Text = 'Boundary';
            app.BoundarySelection = uidropdown(app.BoundariesSelectionPanel);
            app.BoundarySelection.Items = {};
            app.BoundarySelection.ValueChangedFcn = createCallbackFcn(app, @BoundarySelectionValueChanged, true);
            app.BoundarySelection.Value = {};
            app.BoundaryTypeGrid = uigridlayout(app.BoundariesGrid);
            app.BoundaryTypePanel = uipanel(app.BoundaryTypeGrid);
            app.Label = uilabel(app.BoundaryTypePanel);
            app.Label.Text = '';
            app.BoundaryTypeSelection = uidropdown(app.BoundaryTypePanel);
            app.BoundaryTypeSelection.Items = {'Continuity', 'PEC', 'PMC', 'ABC', 'Graphene'};
            app.BoundaryTypeSelection.ValueChangedFcn = createCallbackFcn(app, @BoundaryTypeSelectionValueChanged, true);
            app.BoundaryTypeSelection.Value = 'Continuity';
            app.BoundaryTypeButton = uibutton(app.BoundaryTypeGrid, 'push');
            app.BoundaryTypeButton.ButtonPushedFcn = createCallbackFcn(app, @BoundaryTypeButtonPushed, true);
            app.BoundaryTypeButton.Enable = 'off';
            app.BoundaryTypeButton.Visible = 'off';
            app.BoundaryTypeButton.Text = 'σ';
            app.BoundaryDefine = uibutton(app.BoundariesGrid, 'push');
            app.BoundaryDefine.ButtonPushedFcn = createCallbackFcn(app, @BoundaryDefineButtonPushed, true);
            app.BoundaryDefine.Text = 'Define';
            app.ResultsPanel = uipanel(app.BoundariesAndDomainsGrid);
            app.ResultsGrid = uigridlayout(app.ResultsPanel);
            app.ResSelectionGrid = uigridlayout(app.ResultsGrid);
            app.ResSelection = uidropdown(app.ResSelectionGrid);
            app.ResSelection.Items = {};
            app.ResSelection.Value = {};
            app.UnitLabel = uilabel(app.ResSelectionGrid);
            app.UnitLabel.Text = 'k(ω)';
            app.GridLayout2 = uigridlayout(app.ResultsGrid);
            app.HorizontalPointsLabel = uilabel(app.GridLayout2);
            app.HorizontalPointsLabel.Text = {'Horizontal'; 'Points'};
            app.VerticalPointsLabel = uilabel(app.GridLayout2);
            app.VerticalPointsLabel.Text = {'Vertical '; 'Points'};
            app.Plot_Selection_Grid = uigridlayout(app.ResultsGrid);
            app.HNField = uieditfield(app.Plot_Selection_Grid, 'numeric');
            app.HNField.Limits = [0 Inf];
            app.HNField.Value = 100;
            app.VNField = uieditfield(app.Plot_Selection_Grid, 'numeric');
            app.VNField.Limits = [0 Inf];
            app.VNField.Value = 100;
            app.GridLayout4 = uigridlayout(app.ResultsGrid);
            app.FreqSelection = uidropdown(app.GridLayout4);
            app.FreqSelection.Items = {};
            app.FreqSelection.ValueChangedFcn = createCallbackFcn(app, @FreqSelectionValueChanged, true);
            app.FreqSelection.Value = {};
            app.FreqUnit = uilabel(app.GridLayout4);
            app.FreqUnit.Text = 'GHz';
            app.PlotButton = uibutton(app.ResultsGrid, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SelectButton = uibutton(app.ResultsGrid, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.DoneButton = uibutton(app.ResultsGrid, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.LeftMenuGrid = uigridlayout(app.MainGrid);
            app.ConditionGrid = uigridlayout(app.LeftMenuGrid);
            app.Lamp = uilamp(app.ConditionGrid);
            app.ConditionLabel = uilabel(app.ConditionGrid);
            app.ConditionLabel.Text = '';
            app.TextArea = uitextarea(app.LeftMenuGrid);
            app.LeftSubGrid = uigridlayout(app.LeftMenuGrid);
            app.AssemblyPanel = uipanel(app.LeftSubGrid);
            app.AssemblyGrid = uigridlayout(app.AssemblyPanel);
            app.EquationLabel_1 = uilabel(app.AssemblyGrid);
            app.EquationLabel_1.Text = 'E-H Formulation';
            app.AssemblyButton = uibutton(app.AssemblyGrid, 'push');
            app.AssemblyButton.ButtonPushedFcn = createCallbackFcn(app, @AssemblyButtonPushed, true);
            app.AssemblyButton.Text = 'Assembly';
            app.EquationLabel_2 = uilabel(app.AssemblyGrid);
            app.EquationLabel_2.Text = '(Tangential Normal)';
            app.SolutionPanel = uipanel(app.LeftSubGrid);
            app.SolutionPanel.Enable = 'off';
            app.SolutionPanel.Visible = 'off';
            app.SolutionGrid = uigridlayout(app.SolutionPanel);
            app.NEigenValueField = uieditfield(app.SolutionGrid, 'numeric');
            app.NEigenValueField.Limits = [0 Inf];
            app.NEigenValueField.Value = 5;
            app.ShiftField = uieditfield(app.SolutionGrid, 'numeric');
            app.NumberOfEigenValuesLabel = uilabel(app.SolutionGrid);
            app.NumberOfEigenValuesLabel.Text = 'Number of Eigenvalues';
            app.ShiftLabel = uilabel(app.SolutionGrid);
            app.ShiftLabel.Text = 'Shift';
            app.SolveButton = uibutton(app.SolutionGrid, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.GridLayout4_2 = uigridlayout(app.SolutionGrid);
            app.SolFreq = uidropdown(app.GridLayout4_2);
            app.SolFreq.Items = {};
            app.SolFreq.Value = {};
            app.FreqUnit_2 = uilabel(app.GridLayout4_2);
            app.FreqUnit_2.Text = 'GHz';
            app.GridLayout5 = uigridlayout(app.SolutionGrid);
            app.BetaCheck = uicheckbox(app.GridLayout5);
            app.BetaCheck.ValueChangedFcn = createCallbackFcn(app, @BetaCheckValueChanged, true);
            app.BetaCheck.Text = 'β';
            app.BetaCheck.Value = true;
            app.NeffCheck = uicheckbox(app.GridLayout5);
            app.NeffCheck.ValueChangedFcn = createCallbackFcn(app, @NeffCheckValueChanged, true);
            app.NeffCheck.Text = 'neff';
            % Show the figure after all components are created
            app.EH2D12EigenModeSolverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoDModalSolver(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EH2D12EigenModeSolverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EH2D12EigenModeSolverUIFigure)
        end
    end
end